classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlotHistogramButton     matlab.ui.control.Button
        FeatureDropDown         matlab.ui.control.DropDown
        FeatureDropDownLabel    matlab.ui.control.Label
        AreaField               matlab.ui.control.NumericEditField
        CellCountLabel_2        matlab.ui.control.Label
        CellDataLabel           matlab.ui.control.Label
        CellCountField          matlab.ui.control.NumericEditField
        CellCountLabel          matlab.ui.control.Label
        SegmentButton           matlab.ui.control.Button
        HelpButton              matlab.ui.control.Button
        AdvancedSettingsButton  matlab.ui.control.Button
        UploadImageButton       matlab.ui.control.Button
        SegmentedImageAxes      matlab.ui.control.UIAxes
        OriginalImageAxes       matlab.ui.control.UIAxes
    end

    properties (Access = private)
    cellShapes % Structure to store shape features
    img; % Stores the uploaded image
    segmented_img; % Stores the segmented image
    net; % Stores the pre-trained CNN network
    masks;
    boxes;
    labels;
    scores;
    analysisdat;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select an Image');
            if isequal(file, 0)
                errordlg('No image selected!', 'Error');
                return;
            end
            app.img = imread(fullfile(path, file));
            
            % Convert to grayscale if necessary
            if size(app.img, 3) == 3
                app.img = rgb2gray(app.img);
            end
        
            % Display the uploaded image
            imshow(app.img, 'Parent', app.OriginalImageAxes);
        
        end

        % Callback function
        function SegmentButtonPushed(app, event)
    if isempty(app.img)
        errordlg('Please upload an image first!', 'Error');
        return;
    end

    % Add MRCNNsrc path (Make sure this path is correct)
    addpath(genpath('MRCNNsrc'));

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
            app.net = load('Res101-FINAL.mat', 'net'); % Load the network file
            app.net = app.net.net; % Extract the network object
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end

    % Perform cell segmentation
    try
        [app.segmented_img, cellCount] = segmentCells(app.img, app.net);

        % Display the segmented image with masks
        imshow(app.segmented_img, 'Parent', app.SegmentedImageAxes);

        % Update the cell count field
        app.CellCountField.Value = cellCount;
    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end

        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed2(app, event)
              if isempty(app.img)
        errordlg('Please upload an image first!', 'Error');
        return;
    end

    % Add MRCNNsrc path (Make sure this path is correct)
    addpath(genpath('MRCNNsrc'));

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
            app.net = load('Res101-FINAL.mat'); % Load the network file
            app.net = app.net.net; % Extract the network object
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end

    % Perform cell segmentation
    try
        [app.masks app.labels app.scores app.boxes] = segmentCells(app.net, app.img, NumstrongestRegions=900);
    

        if (isempty(app.masks))
            app.segmented_img = app.img;
        else
            app.segmented_img = insertObjectMask(app.img, app.masks,Color=lines(size(app.masks, 3)) );
        end
        % Display the segmented image with masks
        imshow(app.segmented_img, 'Parent', app.OriginalImageAxes);
        app.analysisdat = ExtractFeatures(app.img, app.masks, app.boxes, 1);

        % Update the cell count field
        app.CellCountField.Value = size(app.analysisdat, 1);
        app.AreaField.Value = mean(app.analysisdat.Area);
    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end

    % Process and extract shape features
    stats = regionprops(segmented_img, 'Area', 'Perimeter','Width','Height', 'Roundness','Aspect Ratio');

    % Compute circularity (4*pi*Area/Perimeter^2)
    Roundness = (4 * pi * [stats.Area]) ./ ([stats.Perimeter].^2);
    
    % Store features in a structured format
    app.cellShapes = struct('Area', [stats.Area], ...
                            'Perimeter', [stats.Perimeter], ...
                            'Roundness', Roundness, ...
                            'Width', [stats.Width], ...
                            'Height', [stats.Height]), ...
                            'Aspect Ratio', [stats.Aspect Ratio];
        end

        % Button pushed function: PlotHistogramButton
        function PlotHistogramButtonPushed(app, event)
            function PlotHistogramButtonPushed(app, event)
    selectedFeature = app.FeatureDropDown.Value; % Get selected feature

    if isempty(app.cellShapes)
        uialert(app.UIFigure, 'Please segment an image first!', 'Error');
        return;
    end

    % Extract the selected feature's values
    featureData = app.cellShapes.(selectedFeature);

    % Plot the histogram
    axes(app.HistogramAxes);
    histogram(featureData, 'FaceColor', 'b');
    title(['Histogram of ', selectedFeature]);
    xlabel(selectedFeature);
    ylabel('Frequency');
    grid on;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            title(app.OriginalImageAxes, 'Original Image')
            app.OriginalImageAxes.XTick = [];
            app.OriginalImageAxes.YTick = [];
            app.SegmentedImageAxes = uiaxes(app.UIFigure);
            app.SegmentedImageAxes.XTick = [];
            app.SegmentedImageAxes.YTick = [];
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.AdvancedSettingsButton = uibutton(app.UIFigure, 'push');
            app.AdvancedSettingsButton.Text = 'Advanced Settings';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help (?)';
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed2, true);
            app.SegmentButton.Text = 'Segment';
            app.CellCountLabel = uilabel(app.UIFigure);
            app.CellCountLabel.Text = 'Number of Cells:';
            app.CellCountField = uieditfield(app.UIFigure, 'numeric');
            app.CellDataLabel = uilabel(app.UIFigure);
            app.CellDataLabel.Text = 'Cell Data';
            app.CellCountLabel_2 = uilabel(app.UIFigure);
            app.CellCountLabel_2.Text = 'Average area (px^2):';
            app.AreaField = uieditfield(app.UIFigure, 'numeric');
            app.FeatureDropDownLabel = uilabel(app.UIFigure);
            app.FeatureDropDownLabel.Text = 'Feature';
            app.FeatureDropDown = uidropdown(app.UIFigure);
            app.FeatureDropDown.Items = {'Area', 'Perimeter', 'Width', 'Height', 'Roundness', 'Aspect Ratio'};
            app.FeatureDropDown.Value = 'Area';
            app.PlotHistogramButton = uibutton(app.UIFigure, 'push');
            app.PlotHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.PlotHistogramButton.Text = 'Plot Histogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end